classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label                  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        SelectafileButton      matlab.ui.control.Button
        FramerateSpinner       matlab.ui.control.Spinner
        FramerateSpinnerLabel  matlab.ui.control.Label
        QualitySlider          matlab.ui.control.Slider
        QualitySliderLabel     matlab.ui.control.Label
        CompressButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        file = "" 
        fps = 30 
        quality = 50 
        path = ""
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CompressButton
        function CompressButtonPushed(app, event)
            if(app.file == "")
                errordlg('File not found','File Error');
            else
            f = msgbox("Loading...");
            vidObj= VideoReader(strcat(app.path, app.file));
            newfilename = strcat("result", string(randi([0, 99999])), ".mp4");
            resultVideo=VideoWriter(strcat(app.path, newfilename),'MPEG-4');
            resultVideo.FrameRate=app.fps;
            resultVideo.Quality=fix(app.quality);
            open(resultVideo)
            while hasFrame(vidObj)
               frame = readFrame(vidObj);
               app.Image.ImageSource = frame;
               writeVideo(resultVideo, frame);
            end
            close(resultVideo);
            delete(f)
            f = msgbox(strcat("Done! File saved as: ", newfilename));
            end
        end

        % Value changed function: FramerateSpinner
        function FramerateSpinnerValueChanged(app, event)
            app.fps = app.FramerateSpinner.Value;
            
        end

        % Value changed function: QualitySlider
        function QualitySliderValueChanged(app, event)
            app.quality = app.QualitySlider.Value;
        end

        % Button pushed function: SelectafileButton
        function SelectafileButtonPushed(app, event)
            [app.file, app.path] = uigetfile("*.mp4");
                
            if(app.file ==0)
            else
                app.Label.Text=app.file;
                preview = VideoReader(strcat(app.path, app.file));
                app.Image.ImageSource = readFrame(preview);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CompressButton = uibutton(app.UIFigure, 'push');
            app.CompressButton.ButtonPushedFcn = createCallbackFcn(app, @CompressButtonPushed, true);
            app.CompressButton.Text = 'Compress';
            app.QualitySliderLabel = uilabel(app.UIFigure);
            app.QualitySliderLabel.Text = 'Quality';
            app.QualitySlider = uislider(app.UIFigure);
            app.QualitySlider.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.QualitySlider.Value = 50;
            app.FramerateSpinnerLabel = uilabel(app.UIFigure);
            app.FramerateSpinnerLabel.Text = 'Framerate';
            app.FramerateSpinner = uispinner(app.UIFigure);
            app.FramerateSpinner.Limits = [0 Inf];
            app.FramerateSpinner.ValueChangedFcn = createCallbackFcn(app, @FramerateSpinnerValueChanged, true);
            app.FramerateSpinner.Value = 30;
            app.SelectafileButton = uibutton(app.UIFigure, 'push');
            app.SelectafileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectafileButtonPushed, true);
            app.SelectafileButton.Text = 'Select a file';
            app.Image = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end